classdef VoiceRecognition0to9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea         matlab.ui.control.TextArea
        Label            matlab.ui.control.Label
        voicerecognize   matlab.ui.control.Button
        playvoice        matlab.ui.control.Button
        stopinputvoice   matlab.ui.control.Button
        startinputvoice  matlab.ui.control.Button
        voiceFS          matlab.ui.control.UIAxes
        voiceTS          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: startinputvoice
        function startinputvoiceButtonPushed(app, event)
            global recorder;
            Fs = 44100;  % 采样频率
            nBits = 24;  % 位深度
            nChannels = 1;  % 单声道
            recorder = audiorecorder(Fs, nBits, nChannels);
            recorder.TimerFcn = {@updatePlot, app}; 
            recorder.TimerPeriod = 0.1;  
            disp('开始录音...');
            record(recorder);
        end

        % Button pushed function: stopinputvoice
        function stopinputvoiceButtonPushed(app, event)
            global recorder;
            global player;
            disp('录音结束.');
            stop(recorder);
            myRecording = getaudiodata(recorder);
            player = audioplayer(myRecording, recorder.SampleRate);
            audiowrite('myRecording.wav', myRecording, recorder.SampleRate);
            plot(app.voiceTS, myRecording);
            title(app.voiceTS, '录音时域波形');
            xlabel(app.voiceTS, '时间（样本）');
            ylabel(app.voiceTS, '振幅');
            % 计算频域表示
            Y = fft(myRecording);
            L = length(myRecording);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = recorder.SampleRate*(0:(L/2))/L;
            
            % 在app.voiceFS坐标图上显示频域波形
            plot(app.voiceFS, f, P1);
            title(app.voiceFS, '录音频域波形');
            xlabel(app.voiceFS, '频率 (Hz)');
            ylabel(app.voiceFS, '幅度');
        end

        % Button pushed function: playvoice
        function playvoiceButtonPushed(app, event)
            global player;
            disp('开始播放...');
            play(player);    
        end

        % Button pushed function: voicerecognize
        function voicerecognizeButtonPushed(app, event)
            % 创建音频特征提取器
            Fs=44100
            afe = audioFeatureExtractor('SampleRate',44100,'mfcc',true);
            
            % 加载并提取myRecording.wav的特征
            [myRecording, Fs] = audioread('myRecording.wav');
            myRecordingFeatures = extract(afe, myRecording);
            
            % 初始化一个结构体来存储音频特征
            voiceFeatures = struct();
            
            % 加载并提取voice0.wav到voice9.wav的特征
            for i = 0:9
                filename = sprintf('voice%d.wav', i);
                [voice, Fs] = audioread(filename);
                voiceFeatures.(sprintf('voice%d', i)) = extract(afe, voice);
            end
            
            % 计算myRecordingFeatures的平均值
            myRecordingFeatures = mean(myRecordingFeatures, 1);
            
            distances = zeros(1, 10);
            
            % 计算myRecordingFeatures和每个voiceFeatures之间的欧氏距离
            for i = 0:9
                voiceFeatures_i = mean(voiceFeatures.(sprintf('voice%d', i)), 1);
                distances(i+1) = sqrt(sum((voiceFeatures_i - myRecordingFeatures).^2));
            end
            
            % 找到最接近的
            [~, recognizedDigit] = min(distances);
            recognizedDigit = recognizedDigit - 1;
            
            % 显示识别的数字
            app.TextArea.Value = ['识别的数字是: ', num2str(recognizedDigit)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.voiceTS = uiaxes(app.UIFigure);
            title(app.voiceTS, 'Title')
            xlabel(app.voiceTS, 'X')
            ylabel(app.voiceTS, 'Y')
            zlabel(app.voiceTS, 'Z')
            app.voiceFS = uiaxes(app.UIFigure);
            title(app.voiceFS, 'Title')
            xlabel(app.voiceFS, 'X')
            ylabel(app.voiceFS, 'Y')
            zlabel(app.voiceFS, 'Z')
            app.startinputvoice = uibutton(app.UIFigure, 'push');
            app.startinputvoice.ButtonPushedFcn = createCallbackFcn(app, @startinputvoiceButtonPushed, true);
            app.startinputvoice.Text = '开始录音';
            app.stopinputvoice = uibutton(app.UIFigure, 'push');
            app.stopinputvoice.ButtonPushedFcn = createCallbackFcn(app, @stopinputvoiceButtonPushed, true);
            app.stopinputvoice.Text = '停止录音';
            app.playvoice = uibutton(app.UIFigure, 'push');
            app.playvoice.ButtonPushedFcn = createCallbackFcn(app, @playvoiceButtonPushed, true);
            app.playvoice.Text = '播放录音';
            app.voicerecognize = uibutton(app.UIFigure, 'push');
            app.voicerecognize.ButtonPushedFcn = createCallbackFcn(app, @voicerecognizeButtonPushed, true);
            app.voicerecognize.Text = '语音识别';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '识别结果';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VoiceRecognition0to9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end