classdef fp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        Phi2EditField         matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        d2EditField           matlab.ui.control.NumericEditField
        dnmEditField_2Label   matlab.ui.control.Label
        n2EditField           matlab.ui.control.NumericEditField
        nEditField_2Label     matlab.ui.control.Label
        r2EditField           matlab.ui.control.NumericEditField
        rEditField_2Label     matlab.ui.control.Label
        A2EditField           matlab.ui.control.NumericEditField
        AEditField_2Label     matlab.ui.control.Label
        Phi1EditField         matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        d1EditField           matlab.ui.control.NumericEditField
        dnmEditFieldLabel     matlab.ui.control.Label
        n1EditField           matlab.ui.control.NumericEditField
        nEditFieldLabel       matlab.ui.control.Label
        r1EditField           matlab.ui.control.NumericEditField
        rEditFieldLabel       matlab.ui.control.Label
        A1EditField           matlab.ui.control.NumericEditField
        AEditFieldLabel       matlab.ui.control.Label
        LengthStepEditField   matlab.ui.control.NumericEditField
        nmEditField_3Label    matlab.ui.control.Label
        LengthUpperEditField  matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        LengthLowerEditField  matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        ShowCurveButton       matlab.ui.control.Button
        SaveImageButton       matlab.ui.control.Button
        CalculateButton       matlab.ui.control.Button
        ImagePanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function Calculate(app, event)
            % 从第一组编辑字段中读取参数
            A1 = app.A1EditField.Value;          % 吸收损耗
            r1 = app.r1EditField.Value;          % 反射率
            n1 = app.n1EditField.Value;          % 介质折射率
            d1 = app.d1EditField.Value;          % 谐振腔厚度（单位：纳米）
            theta1 = app.Theta1EditField.Value;  % 入射角（单位：度）
            phi1 = app.Phi1EditField.Value;      % 相位偏移（单位：弧度）
        
            % 从第二组编辑字段中读取参数
            A2 = app.A2EditField.Value;          % 吸收损耗
            r2 = app.r2EditField.Value;          % 反射率
            n2 = app.n2EditField.Value;          % 介质折射率
            d2 = app.d2EditField.Value;          % 谐振腔厚度（单位：纳米）
            theta2 = app.Theta2EditField.Value;  % 入射角（单位：度）
            phi2 = app.Phi2EditField.Value;      % 相位偏移（单位：弧度）
        
            % 从波长范围和步长字段中读取参数
            lambda_min = app.LengthLowerEditField.Value; % 波长范围下界
            lambda_max = app.LengthUpperEditField.Value; % 波长范围上界
            step = app.LengthStepEditField.Value;        % 波长步长
        
            % 检查参数有效性
            if lambda_min >= lambda_max || step <= 0 || lambda_min<0 || lambda_max<0
                uialert(app.UIFigure, '波长范围或步长设置不正确！波长和步长应都大于零,波长上界大于下界。', '参数错误');
                return;
            end

            if A1 < 0 || A1 > 1 || A2 < 0 || A2 > 1
                uialert(app.UIFigure, '吸收损耗必须在0到1之间！', '参数错误');
                return;
            end

            if r1 < 0 || r1 > 1 || r2 < 0 || r2 > 1
                uialert(app.UIFigure, '反射率必须在0到1之间！', '参数错误');
                return;
            end

            if d1 <= 0 || d2 <= 0
                uialert(app.UIFigure, '谐振腔厚度必须大于0！', '参数错误');
                return;
            end

            if n1 < 1 || n2 < 1
                uialert(app.UIFigure, '介质折射率必须大于等于1！', '参数错误');
                return;
            end

            if theta1 < 0 || theta1 > 90 || theta2 < 0 || theta2 > 90
                uialert(app.UIFigure, '入射角须设置在0到90度之间！', '参数错误');
                return;
            end
        
            % 波长范围
            lambda_range = lambda_min:step:lambda_max;
        
            % 初始化透射率数组
            T_values1 = zeros(size(lambda_range));
            T_values2 = zeros(size(lambda_range));
        
            % 将角度转换为弧度
            theta1_rad = deg2rad(theta1);
            theta2_rad = deg2rad(theta2);
        
            % 计算第一组参数的透射率
            for i = 1:length(lambda_range)
                lambda = lambda_range(i);
                % 公式中的光程部分
                optical_path1 = n1 * d1 * cos(theta1_rad);
                % 计算相位差
                phase_difference1 = (2 * pi / lambda) * optical_path1 - phi1;
                % 计算透射率 T
                denominator1 = 1 + (4 * r1 / (1 - r1)^2) * sin(phase_difference1)^2;
                T_values1(i) = (1 - (A1 / (1 - r1)))^2 * (1 / denominator1);
            end
        
            % 计算第二组参数的透射率
            for i = 1:length(lambda_range)
                lambda = lambda_range(i);
                % 公式中的光程部分
                optical_path2 = n2 * d2 * cos(theta2_rad);
                % 计算相位差
                phase_difference2 = (2 * pi / lambda) * optical_path2 - phi2;
                % 计算透射率 T
                denominator2 = 1 + (4 * r2 / (1 - r2)^2) * sin(phase_difference2)^2;
                T_values2(i) = (1 - (A2 / (1 - r2)))^2 * (1 / denominator2);
            end
        
            % 在 UIAxes 上绘制两条曲线
            plot(app.UIAxes, lambda_range, T_values1, 'b-', 'LineWidth', 1.5); % 第一条曲线（蓝色）
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, lambda_range, T_values2, 'r-', 'LineWidth', 1.5); % 第二条曲线（红色）
            hold(app.UIAxes, 'off');
        
            % 设置图表属性
            xlabel(app.UIAxes, '波长 (nm)');
            ylabel(app.UIAxes, '透射率 T');
            title(app.UIAxes, 'FP谐振器透射率随波长的变化');
            legend(app.UIAxes, {'曲线1', '曲线2'}, 'Location', 'best');
            grid(app.UIAxes, 'on');
        end

        % Button pushed function: SaveImageButton
        function SaveImage(app, event)
            % 弹出保存文件对话框，选择保存路径和文件名
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tif'; '*.pdf'}, '保存图像');
            
            % 如果用户取消保存操作，则直接返回
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % 构造完整的文件路径
            fullFileName = fullfile(path, file);
            
            % 创建一个新的不可见的图形窗口
            fig = figure('Visible', 'off');
            
            % 复制 UIAxes 的内容到新图形窗口
            ax = copyobj(app.UIAxes, fig);
            set(ax, 'Units', 'normalized', 'Position', [0.13, 0.11, 0.775, 0.815]); % 调整轴的位置
            
            % 保存图像到指定路径
            saveas(fig, fullFileName);
            
            % 关闭临时图形窗口
            close(fig);
            
            % 弹出提示框，通知用户保存成功
            uialert(app.UIFigure, '图像已成功保存！', '保存成功');
        end

        % Button pushed function: ShowCurveButton
        function ShowCurve(app, event)
            % 获取当前 UIAxes 中的所有图形对象
            lines = app.UIAxes.Children;
        
            % 遍历图形对象，找到曲线2（假设曲线2是红色的）
            for i = 1:length(lines)
                if isequal(lines(i).Color, [1, 0, 0]) % 红色曲线
                    % 切换曲线的可见性
                    if strcmp(lines(i).Visible, 'on')
                        lines(i).Visible = 'off'; % 隐藏曲线2
                    else
                        lines(i).Visible = 'on';  % 显示曲线2
                    end
                    break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagePanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, 'FP谐振器透射率随波长的变化')
            xlabel(app.UIAxes, '波长(nm)')
            ylabel(app.UIAxes, '透射率T')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel = uipanel(app.UIFigure);
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate, true);
            app.CalculateButton.Text = '计算';
            app.SaveImageButton = uibutton(app.Panel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.SaveImageButton.Text = '保存图像';
            app.ShowCurveButton = uibutton(app.Panel, 'push');
            app.ShowCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurve, true);
            app.ShowCurveButton.Text = '显示/关闭曲线2';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '曲线1参数设置';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '曲线2参数设置';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '波长范围下界(nm)';
            app.LengthLowerEditField = uieditfield(app.Panel, 'numeric');
            app.LengthLowerEditField.Value = 50;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '波长范围上界(nm)';
            app.LengthUpperEditField = uieditfield(app.Panel, 'numeric');
            app.LengthUpperEditField.Value = 1200;
            app.nmEditField_3Label = uilabel(app.Panel);
            app.nmEditField_3Label.Text = '计算步长(nm)';
            app.LengthStepEditField = uieditfield(app.Panel, 'numeric');
            app.LengthStepEditField.Value = 0.01;
            app.AEditFieldLabel = uilabel(app.Panel);
            app.AEditFieldLabel.Text = '吸收损耗A';
            app.A1EditField = uieditfield(app.Panel, 'numeric');
            app.A1EditField.Value = 0.015;
            app.rEditFieldLabel = uilabel(app.Panel);
            app.rEditFieldLabel.Text = '反射率r';
            app.r1EditField = uieditfield(app.Panel, 'numeric');
            app.r1EditField.Value = 0.85;
            app.nEditFieldLabel = uilabel(app.Panel);
            app.nEditFieldLabel.Text = '介质折射率n';
            app.n1EditField = uieditfield(app.Panel, 'numeric');
            app.n1EditField.Value = 1.49;
            app.dnmEditFieldLabel = uilabel(app.Panel);
            app.dnmEditFieldLabel.Text = '谐振腔厚度d(nm)';
            app.d1EditField = uieditfield(app.Panel, 'numeric');
            app.d1EditField.Value = 300;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '入射角θ(°)';
            app.Theta1EditField = uieditfield(app.Panel, 'numeric');
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '相位偏移φ(弧度)';
            app.Phi1EditField = uieditfield(app.Panel, 'numeric');
            app.AEditField_2Label = uilabel(app.Panel);
            app.AEditField_2Label.Text = '吸收损耗A';
            app.A2EditField = uieditfield(app.Panel, 'numeric');
            app.A2EditField.Value = 0.015;
            app.rEditField_2Label = uilabel(app.Panel);
            app.rEditField_2Label.Text = '反射率r';
            app.r2EditField = uieditfield(app.Panel, 'numeric');
            app.r2EditField.Value = 0.85;
            app.nEditField_2Label = uilabel(app.Panel);
            app.nEditField_2Label.Text = '介质折射率n';
            app.n2EditField = uieditfield(app.Panel, 'numeric');
            app.n2EditField.Value = 1.49;
            app.dnmEditField_2Label = uilabel(app.Panel);
            app.dnmEditField_2Label.Text = '谐振腔厚度d(nm)';
            app.d2EditField = uieditfield(app.Panel, 'numeric');
            app.d2EditField.Value = 600;
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = '入射角θ(°)';
            app.Theta2EditField = uieditfield(app.Panel, 'numeric');
            app.EditField_4Label = uilabel(app.Panel);
            app.EditField_4Label.Text = '相位偏移φ(弧度)';
            app.Phi2EditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end